classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GuimtarUIFigure       matlab.ui.Figure
        ChooseyourtuningMenu  matlab.ui.container.Menu
        StandardEMenu         matlab.ui.container.Menu
        DropDMenu             matlab.ui.container.Menu
        StandardDMenu         matlab.ui.container.Menu
        DropCMenu             matlab.ui.container.Menu
        stringsBStandardMenu  matlab.ui.container.Menu
        stringsDropAMenu      matlab.ui.container.Menu
        Image                 matlab.ui.control.Image
        Image2                matlab.ui.control.Image
        Image3                matlab.ui.control.Image
        Image4                matlab.ui.control.Image
        Image5                matlab.ui.control.Image
        Image6                matlab.ui.control.Image
        Image7                matlab.ui.control.Image
        Button                matlab.ui.control.Button
        Button2               matlab.ui.control.Button
        Button3               matlab.ui.control.Button
        Button4               matlab.ui.control.Button
        Button5               matlab.ui.control.Button
        Button6               matlab.ui.control.Button
        Button7               matlab.ui.control.Button
        Image8                matlab.ui.control.Image
        Gauge                 matlab.ui.control.SemicircularGauge
        PitchLabel            matlab.ui.control.Label
        DropDownLabel         matlab.ui.control.Label
        DropDown              matlab.ui.control.DropDown
        STARTButton           matlab.ui.control.Button
        TempoKnobLabel        matlab.ui.control.Label
        TempoKnob             matlab.ui.control.Knob
        MetrumSpinnerLabel    matlab.ui.control.Label
        MetrumSpinner         matlab.ui.control.Spinner
        ButtonGroup           matlab.ui.container.ButtonGroup
        tokButton             matlab.ui.control.ToggleButton
        takButton             matlab.ui.control.ToggleButton
        tikButton             matlab.ui.control.ToggleButton
        ButtonGroup_2         matlab.ui.container.ButtonGroup
        tokButton_2           matlab.ui.control.ToggleButton
        takButton_2           matlab.ui.control.ToggleButton
        tikButton_2           matlab.ui.control.ToggleButton
        ButtonGroup_3         matlab.ui.container.ButtonGroup
        tokButton_3           matlab.ui.control.ToggleButton
        takButton_3           matlab.ui.control.ToggleButton
        tikButton_3           matlab.ui.control.ToggleButton
        ButtonGroup_4         matlab.ui.container.ButtonGroup
        tokButton_4           matlab.ui.control.ToggleButton
        takButton_4           matlab.ui.control.ToggleButton
        tikButton_4           matlab.ui.control.ToggleButton
        ButtonGroup_5         matlab.ui.container.ButtonGroup
        tokButton_5           matlab.ui.control.ToggleButton
        takButton_5           matlab.ui.control.ToggleButton
        tikButton_5           matlab.ui.control.ToggleButton
        ButtonGroup_6         matlab.ui.container.ButtonGroup
        tokButton_6           matlab.ui.control.ToggleButton
        takButton_6           matlab.ui.control.ToggleButton
        tikButton_6           matlab.ui.control.ToggleButton
        ButtonGroup_7         matlab.ui.container.ButtonGroup
        tokButton_7           matlab.ui.control.ToggleButton
        takButton_7           matlab.ui.control.ToggleButton
        tikButton_7           matlab.ui.control.ToggleButton
        ButtonGroup_8         matlab.ui.container.ButtonGroup
        tokButton_8           matlab.ui.control.ToggleButton
        takButton_8           matlab.ui.control.ToggleButton
        tikButton_8           matlab.ui.control.ToggleButton
        BpmSpinnerLabel       matlab.ui.control.Label
        BpmSpinner            matlab.ui.control.Spinner
        Label                 matlab.ui.control.Label
        Spinner               matlab.ui.control.Spinner
    end

    
    properties (Access = public)
        Frequencies = []
        value = 0% Description
        counter = 0; % Description
        play ; % Description
        previousValue = 4;
    end

    
    methods (Access = private)
        
        function chooseString(app)
            while true
                recObj = audiorecorder(44100, 16, 1);
                get(recObj);
                recordblocking(recObj, 0.5);
                myRecording = getaudiodata(recObj);
                f0 = pitch(myRecording,44100);
                stringNumber = 1;
                actual_tune = mode(f0); 
                if actual_tune < app.Frequencies(1,app.value) + 20 && actual_tune > app.Frequencies(1,app.value) - 20 %pierwsza struna
                    app.Button.BackgroundColor = [0.21,0.15,0.59];
                    app.Button2.BackgroundColor = [1, 1, 1];
                    app.Button3.BackgroundColor = [1, 1, 1];
                    app.Button4.BackgroundColor = [1, 1, 1];
                    app.Button5.BackgroundColor = [1, 1, 1];
                    app.Button6.BackgroundColor = [1, 1, 1];
                    app.Button7.BackgroundColor = [1, 1, 1];
                    stringNumber =1;
                elseif actual_tune < app.Frequencies(2,app.value) + 20 && actual_tune > app.Frequencies(2,app.value) - 20 %druga struna
                    app.Button.BackgroundColor = [1, 1, 1];
                    app.Button2.BackgroundColor = [0.21,0.15,0.59];
                    app.Button3.BackgroundColor = [1, 1, 1];
                    app.Button4.BackgroundColor = [1, 1, 1];
                    app.Button5.BackgroundColor = [1, 1, 1];
                    app.Button6.BackgroundColor = [1, 1, 1];
                    app.Button7.BackgroundColor = [1, 1, 1];
                    stringNumber =2;
                elseif actual_tune < app.Frequencies(3,app.value) + 20 && actual_tune > app.Frequencies(3,app.value) - 20 %trzecia struna
                    app.Button.BackgroundColor = [1, 1, 1];
                    app.Button2.BackgroundColor = [1, 1, 1];
                    app.Button3.BackgroundColor = [0.21,0.15,0.59];
                    app.Button4.BackgroundColor = [1, 1, 1];
                    app.Button5.BackgroundColor = [1, 1, 1];
                    app.Button6.BackgroundColor = [1, 1, 1];
                    app.Button7.BackgroundColor = [1, 1, 1];
                    stringNumber =3;
                elseif actual_tune < app.Frequencies(4,app.value) + 15 && actual_tune > app.Frequencies(4,app.value) - 15 %czwarta
                    app.Button.BackgroundColor = [1, 1, 1];
                    app.Button2.BackgroundColor = [1, 1, 1];
                    app.Button3.BackgroundColor = [1, 1, 1];
                    app.Button4.BackgroundColor = [0.21,0.15,0.59];
                    app.Button5.BackgroundColor = [1, 1, 1];
                    app.Button6.BackgroundColor = [1, 1, 1];
                    app.Button7.BackgroundColor = [1, 1, 1];
                    stringNumber =4;
                elseif actual_tune < app.Frequencies(5,app.value) + 10 && actual_tune > app.Frequencies(5,app.value) - 10 %piąta struna
                    app.Button.BackgroundColor = [1, 1, 1];
                    app.Button2.BackgroundColor = [1, 1, 1];
                    app.Button3.BackgroundColor = [1, 1, 1];
                    app.Button4.BackgroundColor = [1, 1, 1];
                    app.Button5.BackgroundColor = [0.21,0.15,0.59];
                    app.Button6.BackgroundColor = [1, 1, 1];
                    app.Button7.BackgroundColor = [1, 1, 1];
                    stringNumber =5;
                elseif actual_tune < app.Frequencies(6,app.value) + 10 && actual_tune > app.Frequencies(6,app.value) - 10 %szósta struna
                    app.Button.BackgroundColor = [1, 1, 1];
                    app.Button2.BackgroundColor = [1, 1, 1];
                    app.Button3.BackgroundColor = [1, 1, 1];
                    app.Button4.BackgroundColor = [1, 1, 1];
                    app.Button5.BackgroundColor = [1, 1, 1];
                    app.Button6.BackgroundColor = [0.21,0.15,0.59];
                    app.Button7.BackgroundColor = [1, 1, 1];
                    stringNumber =6;
                elseif actual_tune < app.Frequencies(7,app.value) + 10 && actual_tune > app.Frequencies(7,app.value) - 10 %siódma struna
                    app.Button.BackgroundColor = [1, 1, 1];
                    app.Button2.BackgroundColor = [1, 1, 1];
                    app.Button3.BackgroundColor = [1, 1, 1];
                    app.Button4.BackgroundColor = [1, 1, 1];
                    app.Button5.BackgroundColor = [1, 1, 1];
                    app.Button6.BackgroundColor = [1, 1, 1];
                    app.Button7.BackgroundColor = [0.21,0.15,0.59];
                    stringNumber =7;
                else
                    set(app.PitchLabel ,'FontColor', [1 0 0])
                end
                tuneString(app, app.Frequencies(stringNumber,app.value), actual_tune)
                app.Gauge.Value=actual_tune;
                app.PitchLabel.Text = actual_tune + " Hz";
            end
        end
        
        function tuneString(app, correct_tune, actual_tune)
            tune_rounded = round(correct_tune)
            app.Gauge.Limits = [tune_rounded-10, tune_rounded+10];
             if (actual_tune < correct_tune+0.75 && actual_tune > correct_tune-0.75)
                    set(app.PitchLabel ,'FontColor', [0 1 0])
             else
                    set(app.PitchLabel ,'FontColor', [1 0 0])
             end
        end


        function results = startPlaying(app)
             app.BpmSpinner.Value = round(app.TempoKnob.Value);   
             [tic,fs] = audioread('tic.wav');
             [tac,fs] = audioread('toc.wav');
             [toc,fs] = audioread('tac.wav');
             tempo = round(app.TempoKnob.Value);
             %fs = fs * speed;
             noteTime = 60/tempo;
             app.play = true;
             while app.play == true
                 for i= 1:app.MetrumSpinner.Value
                     
                     %if i == 1
                     %    [file,fs] = audioread('tic.mp3');
                     %else
                     %    [file,fs] = audioread('tac.mp3');
                     %end
                     
                     if i== 1 && app.tikButton.Value == true
                         sound(tic,fs);
                     elseif i== 1 && app.takButton.Value == true
                         sound(tac,fs);
                     elseif i== 1 && app.tokButton.Value == true
                         sound(toc,fs);
                     end
                     if i== 2 && app.tikButton_2.Value == true
                         sound(tic,fs);
                     elseif i== 2 && app.takButton_2.Value == true
                         sound(tac,fs);
                     elseif i== 2 && app.tokButton_2.Value == true
                         sound(toc,fs);
                     end
                     if i== 3 && app.tikButton_3.Value == true
                         sound(tic,fs);
                     elseif i== 3 && app.takButton_3.Value == true
                         sound(tac,fs);
                     elseif i== 3 && app.tokButton_3.Value == true
                         sound(toc,fs);
                     end
                     if i== 4 && app.tikButton_4.Value == true
                         sound(tic,fs);
                     elseif i== 4 && app.takButton_4.Value == true
                         sound(tac,fs);
                     elseif i== 4 && app.tokButton_4.Value == true
                         sound(toc,fs);
                     end
                     if i== 5 && app.tikButton_5.Value == true
                         sound(tic,fs);
                     elseif i== 5 && app.takButton_5.Value == true
                         sound(tac,fs);
                     elseif i== 5 && app.tokButton_5.Value == true
                         sound(toc,fs);
                     end
                     if i== 6 && app.tikButton_6.Value == true
                         sound(tic,fs);
                     elseif i== 6 && app.takButton_6.Value == true
                         sound(tac,fs);
                     elseif i== 6 && app.tokButton_6.Value == true
                         sound(toc,fs);
                     end
                     if i== 7 && app.tikButton_7.Value == true
                         sound(tic,fs);
                     elseif i== 7 && app.takButton_7.Value == true
                         sound(tac,fs);
                     elseif i== 7 && app.tokButton_7.Value == true
                         sound(toc,fs);
                     end
                     if i== 8 && app.tikButton_8.Value == true
                         sound(tic,fs);
                     elseif i== 8 && app.takButton_8.Value == true
                         sound(tac,fs);
                     elseif i== 8 && app.tokButton_8.Value == true
                         sound(toc,fs);
                     end
                 pause(noteTime);
                 end
            end
        end
        
        function results = stopPlaying(app)
            app.play = false;
            clear sound;
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function STOPButtonPushed(app, event)
            stopPlaying(app);
        end

        % Image clicked function: Image3
        function tunerOnClick(app, event)
            app.Gauge.Visible = "on";
            app.PitchLabel.Visible = "on";
            load('frequencies',"-mat","Frequencies")
            app.Frequencies = Frequencies;
            app.Image3.delete
            app.Image4.delete
            app.Image5.delete
            app.Image6.delete
            app.Image7.delete
            app.ChooseyourtuningMenu.Visible = 'on';
        end

        % Menu selected function: DropDMenu
        function dropD(app, event)
            app.value = 2;
            app.StandardEMenu.Checked = 'off';
            app.DropDMenu.Checked = 'on';
            app.StandardDMenu.Checked = 'off';
            app.DropCMenu.Checked = 'off';
            app.stringsBStandardMenu.Checked = 'off';
            app.stringsDropAMenu.Checked = 'off';
            app.Button.Visible = 'on';
            app.Button.Text = "E4 - " + num2str(app.Frequencies(1,2)) + " Hz";
            app.Button2.Visible = 'on';
            app.Button2.Text = "B3 - " + num2str(app.Frequencies(2,2))+ " Hz";
            app.Button3.Visible = 'on';
            app.Button3.Text = "G3 - " + num2str(app.Frequencies(3,2))+ " Hz";
            app.Button4.Visible = 'on';
            app.Button4.Text = "D3 - " + num2str(app.Frequencies(4,2))+ " Hz";
            app.Button5.Visible = 'on';
            app.Button5.Text = "A2 - " + num2str(app.Frequencies(5,2))+ " Hz";
            app.Button6.Visible = 'on';
            app.Button6.Text = "D2 - " + num2str(app.Frequencies(6,2))+ " Hz"; 
            app.Button7.Visible = 'off';
            chooseString(app)  
        end

        % Menu selected function: DropCMenu
        function dropC(app, event)
            app.value = 4;
            app.StandardEMenu.Checked = 'off';
            app.DropDMenu.Checked = 'off';
            app.StandardDMenu.Checked = 'off';
            app.DropCMenu.Checked = 'on';
            app.stringsBStandardMenu.Checked = 'off';
            app.stringsDropAMenu.Checked = 'off';
            app.Button.Visible = 'on';
            app.Button.Text = "D4 - " + num2str(app.Frequencies(1,4)) + " Hz";
            app.Button2.Visible = 'on';
            app.Button2.Text = "A3 - " + num2str(app.Frequencies(2,4))+ " Hz";
            app.Button3.Visible = 'on';
            app.Button3.Text = "F3 - " + num2str(app.Frequencies(3,4))+ " Hz";
            app.Button4.Visible = 'on';
            app.Button4.Text = "C3 - " + num2str(app.Frequencies(4,4))+ " Hz";
            app.Button5.Visible = 'on';
            app.Button5.Text = "G2 - " + num2str(app.Frequencies(5,4))+ " Hz";
            app.Button6.Visible = 'on';
            app.Button6.Text = "C2 - " + num2str(app.Frequencies(6,4))+ " Hz";
            app.Button7.Visible = 'off';
            chooseString(app)  
        end

        % Menu selected function: stringsBStandardMenu
        function bSevenStandard(app, event)
            app.value = 5;
            app.StandardEMenu.Checked = 'off';
            app.DropDMenu.Checked = 'off';
            app.StandardDMenu.Checked = 'off';
            app.DropCMenu.Checked = 'off';
            app.stringsBStandardMenu.Checked = 'on';
            app.stringsDropAMenu.Checked = 'off';
            app.Button.Visible = 'on';
            app.Button.Text = "E4 - " + num2str(app.Frequencies(1,5)) + " Hz";
            app.Button2.Visible = 'on';
            app.Button2.Text = "B3 - " + num2str(app.Frequencies(2,5))+ " Hz";
            app.Button3.Visible = 'on';
            app.Button3.Text = "G3 - " + num2str(app.Frequencies(3,5))+ " Hz";
            app.Button4.Visible = 'on';
            app.Button4.Text = "D3 - " + num2str(app.Frequencies(4,5))+ " Hz";
            app.Button5.Visible = 'on';
            app.Button5.Text = "A2 - " + num2str(app.Frequencies(5,5))+ " Hz";
            app.Button6.Visible = 'on';
            app.Button6.Text = "E2 - " + num2str(app.Frequencies(6,5))+ " Hz";  
            app.Button7.Visible = 'on';
            app.Button7.Text = "B1 - " + num2str(app.Frequencies(7,5))+ " Hz"; 
            chooseString(app)
        end

        % Image clicked function: Image4
        function metronomeOnClick(app, event)
            app.Image3.delete
            app.Image4.delete
            app.Image5.delete
            app.Image6.delete
            app.Image7.delete
            
            app.TempoKnob.Visible = "on";
            app.BpmSpinner.Visible = "on";
            app.MetrumSpinner.Visible = "on";
            app.MetrumSpinnerLabel.Visible = "on";
            app.Spinner.Visible = "on";
            app.BpmSpinnerLabel.Visible = "on";
            app.BpmSpinner.Visible = "on";
            app.STARTButton.Visible = "on";
            app.ButtonGroup.Visible = "on";
            app.ButtonGroup_2.Visible = "on";
            app.ButtonGroup_3.Visible = "on";
            app.ButtonGroup_4.Visible = "on";
        end

        % Image clicked function: Image5
        function soundRecorderOnClick(app, event)
            app.Image3.delete
            app.Image4.delete
            app.Image5.delete
            app.Image6.delete
            app.Image7.delete
        end

        % Image clicked function: Image6
        function tabsCollectionOnClick(app, event)
            app.Image3.delete
            app.Image4.delete
            app.Image5.delete
            app.Image6.delete
            app.Image7.delete
        end

        % Image clicked function: Image7
        function myEquipmentOnClick(app, event)
            app.Image3.delete
            app.Image4.delete
            app.Image5.delete
            app.Image6.delete
            app.Image7.delete
        end

        % Image clicked function: Image2, Image8
        function home(app, event)
            app.ChooseyourtuningMenu.Visible = 'off';
                        % Create ChooseyourtuningMenu
            app.ChooseyourtuningMenu = uimenu(app.GuimtarUIFigure);
            app.ChooseyourtuningMenu.Visible = 'off';
            app.ChooseyourtuningMenu.Text = 'Choose your tuning';

            % Create StandardEMenu
            app.StandardEMenu = uimenu(app.ChooseyourtuningMenu);
            app.StandardEMenu.MenuSelectedFcn = createCallbackFcn(app, @standardE, true);
            app.StandardEMenu.Text = 'Standard E';

            % Create DropDMenu
            app.DropDMenu = uimenu(app.ChooseyourtuningMenu);
            app.DropDMenu.MenuSelectedFcn = createCallbackFcn(app, @dropD, true);
            app.DropDMenu.Text = 'Drop D';

            % Create StandardDMenu
            app.StandardDMenu = uimenu(app.ChooseyourtuningMenu);
            app.StandardDMenu.MenuSelectedFcn = createCallbackFcn(app, @standardD, true);
            app.StandardDMenu.Text = 'Standard D';

            % Create DropCMenu
            app.DropCMenu = uimenu(app.ChooseyourtuningMenu);
            app.DropCMenu.MenuSelectedFcn = createCallbackFcn(app, @dropC, true);
            app.DropCMenu.Text = 'Drop C';

            % Create stringsBStandardMenu
            app.stringsBStandardMenu = uimenu(app.ChooseyourtuningMenu);
            app.stringsBStandardMenu.MenuSelectedFcn = createCallbackFcn(app, @bSevenStandard, true);
            app.stringsBStandardMenu.Text = '7 strings B Standard';
            
            % Create stringsDropAMenu
            app.stringsDropAMenu = uimenu(app.ChooseyourtuningMenu);
            app.stringsDropAMenu.MenuSelectedFcn = createCallbackFcn(app, @aSevenString, true);
            app.stringsDropAMenu.Text = '7 strings Drop A';

            % Create Image
            app.Image = uiimage(app.GuimtarUIFigure);
            app.Image.ScaleMethod = 'fill';
            app.Image.Position = [1 -103 640 650];
            app.Image.ImageSource = 'azure-linear-gradient-magenta-1920x1080-c2-04273d-33043d-a-60-f-14.jpg';

            % Create Image2
            app.Image2 = uiimage(app.GuimtarUIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @home, true);
            app.Image2.Position = [225 377 191 170];
            app.Image2.ImageSource = 'logo.png';

            % Create Image3
            app.Image3 = uiimage(app.GuimtarUIFigure);
            app.Image3.ScaleMethod = 'none';
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @tunerOnClick, true);
            app.Image3.Position = [96 313 450 100];
            app.Image3.ImageSource = 'button_guitar-tuner.png';

            % Create Image4
            app.Image4 = uiimage(app.GuimtarUIFigure);
            app.Image4.ScaleMethod = 'none';
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @metronomeOnClick, true);
            app.Image4.Position = [120 237 401 100];
            app.Image4.ImageSource = 'button_metronome.png';

            % Create Image5
            app.Image5 = uiimage(app.GuimtarUIFigure);
            app.Image5.ScaleMethod = 'none';
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @soundRecorderOnClick, true);
            app.Image5.Position = [120 162 401 100];
            app.Image5.ImageSource = 'button_sound-recorder.png';

            % Create Image6
            app.Image6 = uiimage(app.GuimtarUIFigure);
            app.Image6.ScaleMethod = 'none';
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @tabsCollectionOnClick, true);
            app.Image6.Position = [120 85 401 100];
            app.Image6.ImageSource = 'button_tabs-collection.png';

            % Create Image7
            app.Image7 = uiimage(app.GuimtarUIFigure);
            app.Image7.ScaleMethod = 'none';
            app.Image7.ImageClickedFcn = createCallbackFcn(app, @myEquipmentOnClick, true);
            app.Image7.Position = [120 9 401 100];
            app.Image7.ImageSource = 'button_my-equipment.png';

            % Create Button
            app.Button = uibutton(app.GuimtarUIFigure, 'push');
            app.Button.Visible = 'off';
            app.Button.Position = [120 366 100 22];

            % Create Button2
            app.Button2 = uibutton(app.GuimtarUIFigure, 'push');
            app.Button2.BackgroundColor = [1 1 1];
            app.Button2.Visible = 'off';
            app.Button2.Position = [271 366 100 22];
            app.Button2.Text = 'Button2';

            % Create Button3
            app.Button3 = uibutton(app.GuimtarUIFigure, 'push');
            app.Button3.Visible = 'off';
            app.Button3.Position = [421 366 100 22];
            app.Button3.Text = 'Button3';

            % Create Button4
            app.Button4 = uibutton(app.GuimtarUIFigure, 'push');
            app.Button4.Visible = 'off';
            app.Button4.Position = [120 292 100 22];
            app.Button4.Text = 'Button4';

            % Create Button5
            app.Button5 = uibutton(app.GuimtarUIFigure, 'push');
            app.Button5.Visible = 'off';
            app.Button5.Position = [271 292 100 22];
            app.Button5.Text = 'Button5';

            % Create Button6
            app.Button6 = uibutton(app.GuimtarUIFigure, 'push');
            app.Button6.Visible = 'off';
            app.Button6.Position = [421 292 100 22];
            app.Button6.Text = 'Button6';

            % Create Button7
            app.Button7 = uibutton(app.GuimtarUIFigure, 'push');
            app.Button7.Visible = 'off';
            app.Button7.Position = [120 211 100 22];
            app.Button7.Text = 'Button7';

            % Create Image8
            app.Image8 = uiimage(app.GuimtarUIFigure);
            app.Image8.ScaleMethod = 'fill';
            app.Image8.ImageClickedFcn = createCallbackFcn(app, @home, true);
            app.Image8.Position = [541 162 100 100];
            app.Image8.ImageSource = 'home.png';

            % Create Gauge
            app.Gauge = uigauge(app.GuimtarUIFigure, 'semicircular');
            app.Gauge.Visible = 'off';
            app.Gauge.BackgroundColor = [0.1373 0.1373 0.6314];
            app.Gauge.FontName = 'Verdana';
            app.Gauge.FontColor = [0.7176 0.2745 1];
            app.Gauge.Position = [191 64 260 141];

            % Create PitchLabel
            app.PitchLabel = uilabel(app.GuimtarUIFigure);
            app.PitchLabel.HorizontalAlignment = 'center';
            app.PitchLabel.FontSize = 20;
            app.PitchLabel.FontColor = [0.7176 0.2745 1];
            app.PitchLabel.Visible = 'off';
            app.PitchLabel.Position = [271 16 116 49];
            app.PitchLabel.Text = 'Pitch';

            % Create DropDownLabel
            app.DropDownLabel = uilabel(app.GuimtarUIFigure);
            app.DropDownLabel.HorizontalAlignment = 'right';
            app.DropDownLabel.Position = [414 467 66 22];
            app.DropDownLabel.Text = 'Drop Down';

            % Create DropDown
            app.DropDown = uidropdown(app.GuimtarUIFigure);
            app.DropDown.Items = {'Standard E', 'Drop D', 'Standard D', 'Drop C', 'Standard B 7 Strings'};
            app.DropDown.Visible = 'off';
            app.DropDown.FontWeight = 'bold';
            app.DropDown.BackgroundColor = [0.502 0.502 0.502];
            app.DropDown.Position = [51 451 124 22];
            app.DropDown.Value = 'Standard E';

            % Create STARTButton
            app.STARTButton = uibutton(app.GuimtarUIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.BackgroundColor = [0.7176 0.2745 1];
            app.STARTButton.FontColor = [0.0078 0.0196 0.0196];
            app.STARTButton.Visible = 'off';
            app.STARTButton.Position = [270 153 100 22];
            app.STARTButton.Text = 'START';

            % Create TempoKnobLabel
            app.TempoKnobLabel = uilabel(app.GuimtarUIFigure);
            app.TempoKnobLabel.HorizontalAlignment = 'center';
            app.TempoKnobLabel.Visible = 'off';
            app.TempoKnobLabel.Position = [301 232 42 22];
            app.TempoKnobLabel.Text = 'Tempo';

            % Create TempoKnob
            app.TempoKnob = uiknob(app.GuimtarUIFigure, 'continuous');
            app.TempoKnob.Limits = [60 300];
            app.TempoKnob.ValueChangedFcn = createCallbackFcn(app, @change, true);
            app.TempoKnob.Visible = 'off';
            app.TempoKnob.FontColor = [1 1 1];
            app.TempoKnob.Position = [291 288 60 60];
            app.TempoKnob.Value = 60;

            % Create MetrumSpinnerLabel
            app.MetrumSpinnerLabel = uilabel(app.GuimtarUIFigure);
            app.MetrumSpinnerLabel.HorizontalAlignment = 'right';
            app.MetrumSpinnerLabel.FontColor = [0.7176 0.2745 1];
            app.MetrumSpinnerLabel.Visible = 'off';
            app.MetrumSpinnerLabel.Position = [38 350 46 22];
            app.MetrumSpinnerLabel.Text = 'Metrum';

            % Create MetrumSpinner
            app.MetrumSpinner = uispinner(app.GuimtarUIFigure);
            app.MetrumSpinner.Limits = [1 8];
            app.MetrumSpinner.ValueChangedFcn = createCallbackFcn(app, @MetrumSpinnerValueChanged, true);
            app.MetrumSpinner.Visible = 'off';
            app.MetrumSpinner.Position = [99 350 47 22];
            app.MetrumSpinner.Value = 4;

            % Create ButtonGroup
            app.ButtonGroup = uibuttongroup(app.GuimtarUIFigure);
            app.ButtonGroup.AutoResizeChildren = 'off';
            app.ButtonGroup.ForegroundColor = [1 1 1];
            app.ButtonGroup.BorderType = 'none';
            app.ButtonGroup.TitlePosition = 'centertop';
            app.ButtonGroup.Title = '1';
            app.ButtonGroup.Visible = 'off';
            app.ButtonGroup.BackgroundColor = [0 0 0];
            app.ButtonGroup.Position = [21 1 100 106];

            % Create tokButton
            app.tokButton = uitogglebutton(app.ButtonGroup);
            app.tokButton.Text = {'tok'; ''};
            app.tokButton.Position = [30 54 40 22];

            % Create takButton
            app.takButton = uitogglebutton(app.ButtonGroup);
            app.takButton.Text = 'tak';
            app.takButton.Position = [30 33 40 22];

            % Create tikButton
            app.tikButton = uitogglebutton(app.ButtonGroup);
            app.tikButton.Text = 'tik';
            app.tikButton.BackgroundColor = [1 1 1];
            app.tikButton.Position = [30 12 40 22];
            app.tikButton.Value = true;

            % Create ButtonGroup_2
            app.ButtonGroup_2 = uibuttongroup(app.GuimtarUIFigure);
            app.ButtonGroup_2.AutoResizeChildren = 'off';
            app.ButtonGroup_2.ForegroundColor = [1 1 1];
            app.ButtonGroup_2.BorderType = 'none';
            app.ButtonGroup_2.TitlePosition = 'centertop';
            app.ButtonGroup_2.Title = '2';
            app.ButtonGroup_2.Visible = 'off';
            app.ButtonGroup_2.BackgroundColor = [0 0 0];
            app.ButtonGroup_2.Position = [94 1 100 106];

            % Create tokButton_2
            app.tokButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.tokButton_2.Text = 'tok';
            app.tokButton_2.Position = [30 54 40 22];

            % Create takButton_2
            app.takButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.takButton_2.Text = 'tak';
            app.takButton_2.Position = [30 33 40 22];

            % Create tikButton_2
            app.tikButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.tikButton_2.Text = 'tik';
            app.tikButton_2.Position = [30 12 40 22];
            app.tikButton_2.Value = true;

            % Create ButtonGroup_3
            app.ButtonGroup_3 = uibuttongroup(app.GuimtarUIFigure);
            app.ButtonGroup_3.AutoResizeChildren = 'off';
            app.ButtonGroup_3.ForegroundColor = [1 1 1];
            app.ButtonGroup_3.BorderType = 'none';
            app.ButtonGroup_3.TitlePosition = 'centertop';
            app.ButtonGroup_3.Title = '3';
            app.ButtonGroup_3.Visible = 'off';
            app.ButtonGroup_3.BackgroundColor = [0 0 0];
            app.ButtonGroup_3.Position = [167 1 100 106];

            % Create tokButton_3
            app.tokButton_3 = uitogglebutton(app.ButtonGroup_3);
            app.tokButton_3.Text = 'tok';
            app.tokButton_3.Position = [30 54 40 22];

            % Create takButton_3
            app.takButton_3 = uitogglebutton(app.ButtonGroup_3);
            app.takButton_3.Text = 'tak';
            app.takButton_3.Position = [30 33 40 22];

            % Create tikButton_3
            app.tikButton_3 = uitogglebutton(app.ButtonGroup_3);
            app.tikButton_3.Text = {'tik'; ''};
            app.tikButton_3.Position = [30 12 40 22];
            app.tikButton_3.Value = true;

            % Create ButtonGroup_4
            app.ButtonGroup_4 = uibuttongroup(app.GuimtarUIFigure);
            app.ButtonGroup_4.AutoResizeChildren = 'off';
            app.ButtonGroup_4.ForegroundColor = [1 1 1];
            app.ButtonGroup_4.BorderType = 'none';
            app.ButtonGroup_4.TitlePosition = 'centertop';
            app.ButtonGroup_4.Title = '4';
            app.ButtonGroup_4.Visible = 'off';
            app.ButtonGroup_4.BackgroundColor = [0 0 0];
            app.ButtonGroup_4.Position = [240 1 100 106];

            % Create tokButton_4
            app.tokButton_4 = uitogglebutton(app.ButtonGroup_4);
            app.tokButton_4.Text = 'tok';
            app.tokButton_4.Position = [30 54 40 22];

            % Create takButton_4
            app.takButton_4 = uitogglebutton(app.ButtonGroup_4);
            app.takButton_4.Text = 'tak';
            app.takButton_4.Position = [30 33 40 22];

            % Create tikButton_4
            app.tikButton_4 = uitogglebutton(app.ButtonGroup_4);
            app.tikButton_4.Text = {'tik'; ''};
            app.tikButton_4.Position = [30 12 40 22];
            app.tikButton_4.Value = true;

            % Create ButtonGroup_5
            app.ButtonGroup_5 = uibuttongroup(app.GuimtarUIFigure);
            app.ButtonGroup_5.AutoResizeChildren = 'off';
            app.ButtonGroup_5.ForegroundColor = [1 1 1];
            app.ButtonGroup_5.BorderType = 'none';
            app.ButtonGroup_5.TitlePosition = 'centertop';
            app.ButtonGroup_5.Title = '5';
            app.ButtonGroup_5.Visible = 'off';
            app.ButtonGroup_5.BackgroundColor = [0 0 0];
            app.ButtonGroup_5.Position = [313 1 100 106];

            % Create tokButton_5
            app.tokButton_5 = uitogglebutton(app.ButtonGroup_5);
            app.tokButton_5.Text = 'tok';
            app.tokButton_5.Position = [30 54 40 22];

            % Create takButton_5
            app.takButton_5 = uitogglebutton(app.ButtonGroup_5);
            app.takButton_5.Text = 'tak';
            app.takButton_5.Position = [30 33 40 22];

            % Create tikButton_5
            app.tikButton_5 = uitogglebutton(app.ButtonGroup_5);
            app.tikButton_5.Text = 'tik';
            app.tikButton_5.Position = [30 12 40 22];
            app.tikButton_5.Value = true;

            % Create ButtonGroup_6
            app.ButtonGroup_6 = uibuttongroup(app.GuimtarUIFigure);
            app.ButtonGroup_6.AutoResizeChildren = 'off';
            app.ButtonGroup_6.ForegroundColor = [1 1 1];
            app.ButtonGroup_6.BorderType = 'none';
            app.ButtonGroup_6.TitlePosition = 'centertop';
            app.ButtonGroup_6.Title = '6';
            app.ButtonGroup_6.Visible = 'off';
            app.ButtonGroup_6.BackgroundColor = [0 0 0];
            app.ButtonGroup_6.Position = [386 1 100 106];

            % Create tokButton_6
            app.tokButton_6 = uitogglebutton(app.ButtonGroup_6);
            app.tokButton_6.Text = {'tok'; ''};
            app.tokButton_6.Position = [30 54 40 22];

            % Create takButton_6
            app.takButton_6 = uitogglebutton(app.ButtonGroup_6);
            app.takButton_6.Text = {'tak'; ''};
            app.takButton_6.Position = [30 33 40 22];

            % Create tikButton_6
            app.tikButton_6 = uitogglebutton(app.ButtonGroup_6);
            app.tikButton_6.Text = {'tik'; ''};
            app.tikButton_6.Position = [30 12 40 22];
            app.tikButton_6.Value = true;

            % Create ButtonGroup_7
            app.ButtonGroup_7 = uibuttongroup(app.GuimtarUIFigure);
            app.ButtonGroup_7.AutoResizeChildren = 'off';
            app.ButtonGroup_7.ForegroundColor = [1 1 1];
            app.ButtonGroup_7.BorderType = 'none';
            app.ButtonGroup_7.TitlePosition = 'centertop';
            app.ButtonGroup_7.Title = '7';
            app.ButtonGroup_7.Visible = 'off';
            app.ButtonGroup_7.BackgroundColor = [0 0 0];
            app.ButtonGroup_7.Position = [459 1 100 106];

            % Create tokButton_7
            app.tokButton_7 = uitogglebutton(app.ButtonGroup_7);
            app.tokButton_7.Text = {'tok'; ''};
            app.tokButton_7.Position = [30 55 40 22];

            % Create takButton_7
            app.takButton_7 = uitogglebutton(app.ButtonGroup_7);
            app.takButton_7.Text = {'tak'; ''};
            app.takButton_7.Position = [30 34 40 22];

            % Create tikButton_7
            app.tikButton_7 = uitogglebutton(app.ButtonGroup_7);
            app.tikButton_7.Text = 'tik';
            app.tikButton_7.Position = [30 13 40 22];
            app.tikButton_7.Value = true;

            % Create ButtonGroup_8
            app.ButtonGroup_8 = uibuttongroup(app.GuimtarUIFigure);
            app.ButtonGroup_8.AutoResizeChildren = 'off';
            app.ButtonGroup_8.ForegroundColor = [1 1 1];
            app.ButtonGroup_8.BorderType = 'none';
            app.ButtonGroup_8.TitlePosition = 'centertop';
            app.ButtonGroup_8.Title = '8';
            app.ButtonGroup_8.Visible = 'off';
            app.ButtonGroup_8.BackgroundColor = [0 0 0];
            app.ButtonGroup_8.Position = [532 1 100 106];

            % Create tokButton_8
            app.tokButton_8 = uitogglebutton(app.ButtonGroup_8);
            app.tokButton_8.Text = {'tok'; ''};
            app.tokButton_8.Position = [30 54 40 22];

            % Create takButton_8
            app.takButton_8 = uitogglebutton(app.ButtonGroup_8);
            app.takButton_8.Text = 'tak';
            app.takButton_8.Position = [30 33 40 22];

            % Create tikButton_8
            app.tikButton_8 = uitogglebutton(app.ButtonGroup_8);
            app.tikButton_8.Text = 'tik';
            app.tikButton_8.Position = [30 12 40 22];
            app.tikButton_8.Value = true;

            % Create BpmSpinnerLabel
            app.BpmSpinnerLabel = uilabel(app.GuimtarUIFigure);
            app.BpmSpinnerLabel.HorizontalAlignment = 'right';
            app.BpmSpinnerLabel.FontColor = [1 0 1];
            app.BpmSpinnerLabel.Visible = 'off';
            app.BpmSpinnerLabel.Position = [474 349 30 22];
            app.BpmSpinnerLabel.Text = 'Bpm';

            % Create BpmSpinner
            app.BpmSpinner = uispinner(app.GuimtarUIFigure);
            app.BpmSpinner.Limits = [60 300];
            app.BpmSpinner.ValueChangedFcn = createCallbackFcn(app, @gaugeChange, true);
            app.BpmSpinner.Visible = 'off';
            app.BpmSpinner.Position = [519 349 109 22];
            app.BpmSpinner.Value = 60;

            % Create Label
            app.Label = uilabel(app.GuimtarUIFigure);
            app.Label.HorizontalAlignment = 'center';
            app.Label.FontColor = [0.7176 0.2745 1];
            app.Label.Visible = 'off';
            app.Label.Position = [145 349 26 22];
            app.Label.Text = '/    ';

            % Create Spinner
            app.Spinner = uispinner(app.GuimtarUIFigure);
            app.Spinner.ValueChangingFcn = createCallbackFcn(app, @setPreviousValue, true);
            app.Spinner.Limits = [0 8];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @noteChange, true);
            app.Spinner.Visible = 'off';
            app.Spinner.Position = [162 350 47 22];
            app.Spinner.Value = 4;

            % Show the figure after all components are created
            app.GuimtarUIFigure.Visible = 'on';

        end

        % Menu selected function: StandardEMenu
        function standardE(app, event)
            app.value = 1;
            app.StandardEMenu.Checked = 'on';
            app.DropDMenu.Checked = 'off';
            app.StandardDMenu.Checked = 'off';
            app.DropCMenu.Checked = 'off';
            app.stringsBStandardMenu.Checked = 'off';
            app.stringsDropAMenu.Checked = 'off';
            app.Button.Visible = 'on';
            app.Button.Text = "E4 - " + num2str(app.Frequencies(1,1)) + " Hz";
            app.Button2.Visible = 'on';
            app.Button2.Text = "B3 - " + num2str(app.Frequencies(2,1))+ " Hz";
            app.Button3.Visible = 'on';
            app.Button3.Text = "G3 - " + num2str(app.Frequencies(3,1))+ " Hz";
            app.Button4.Visible = 'on';
            app.Button4.Text = "D3 - " + num2str(app.Frequencies(4,1))+ " Hz";
            app.Button5.Visible = 'on';
            app.Button5.Text = "A2 - " + num2str(app.Frequencies(5,1))+ " Hz";
            app.Button6.Visible = 'on';
            app.Button6.Text = "E2 - " + num2str(app.Frequencies(6,1))+ " Hz";
            app.Button7.Visible = 'off';    
            chooseString(app)
        end

        % Menu selected function: StandardDMenu
        function standardD(app, event)
            app.value = 3;
            app.StandardEMenu.Checked = 'off';
            app.DropDMenu.Checked = 'off';
            app.StandardDMenu.Checked = 'on';
            app.DropCMenu.Checked = 'off';
            app.stringsBStandardMenu.Checked = 'off'; 
            app.stringsDropAMenu.Checked = 'off';
            app.Button.Visible = 'on';
            app.Button.Text = "D4 - " + num2str(app.Frequencies(1,3)) + " Hz";
            app.Button2.Visible = 'on';
            app.Button2.Text = "A3 - " + num2str(app.Frequencies(2,3))+ " Hz";
            app.Button3.Visible = 'on';
            app.Button3.Text = "F3 - " + num2str(app.Frequencies(3,3))+ " Hz";
            app.Button4.Visible = 'on';
            app.Button4.Text = "C3 - " + num2str(app.Frequencies(4,3))+ " Hz";
            app.Button5.Visible = 'on';
            app.Button5.Text = "G2 - " + num2str(app.Frequencies(5,3))+ " Hz";
            app.Button6.Visible = 'on';
            app.Button6.Text = "D2 - " + num2str(app.Frequencies(6,3))+ " Hz";
            app.Button7.Visible = 'off';
            chooseString(app) 
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.counter = app.counter +1;
            
            if mod(app.counter,2) ~= 0
               
                app.STARTButton.Text = 'STOP';
                startPlaying(app);
                %lampAnimation(app);
            else
                stopPlaying(app);
                app.STARTButton.Text = 'START';

            end
        end

        % Value changed function: TempoKnob
        function change(app, event)
            app.BpmSpinner.Value = round(app.TempoKnob.Value);
        end

        % Value changed function: MetrumSpinner
        function MetrumSpinnerValueChanged(app, event)
            value = app.MetrumSpinner.Value;
            if value == 1
                app.ButtonGroup.Visible = true;
                app.ButtonGroup_2.Visible = false;
                app.ButtonGroup_3.Visible = false;
                app.ButtonGroup_4.Visible = false;
                app.ButtonGroup_5.Visible = false;
                app.ButtonGroup_6.Visible = false;
                app.ButtonGroup_7.Visible = false;
                app.ButtonGroup_8.Visible = false;
            
            elseif value == 2
                app.ButtonGroup.Visible = true;
                app.ButtonGroup_2.Visible = true;
                app.ButtonGroup_3.Visible = false;
                app.ButtonGroup_4.Visible = false;
                app.ButtonGroup_5.Visible = false;
                app.ButtonGroup_6.Visible = false;
                app.ButtonGroup_7.Visible = false;
                app.ButtonGroup_8.Visible = false;
            
            elseif value == 3
                app.ButtonGroup.Visible = true;
                app.ButtonGroup_2.Visible = true;
                app.ButtonGroup_3.Visible = true;
                app.ButtonGroup_4.Visible = false;
                app.ButtonGroup_5.Visible = false;
                app.ButtonGroup_6.Visible = false;
                app.ButtonGroup_7.Visible = false;
                app.ButtonGroup_8.Visible = false;
            
            elseif value == 4
                app.ButtonGroup.Visible = true;
                app.ButtonGroup_2.Visible = true;
                app.ButtonGroup_3.Visible = true;
                app.ButtonGroup_4.Visible = true;
                app.ButtonGroup_5.Visible = false;
                app.ButtonGroup_6.Visible = false;
                app.ButtonGroup_7.Visible = false;
                app.ButtonGroup_8.Visible = false;
                
            elseif value == 5
                app.ButtonGroup.Visible = true;
                app.ButtonGroup_2.Visible = true;
                app.ButtonGroup_3.Visible = true;
                app.ButtonGroup_4.Visible = true;
                app.ButtonGroup_5.Visible = true;
                app.ButtonGroup_6.Visible = false;
                app.ButtonGroup_7.Visible = false;
                app.ButtonGroup_8.Visible = false;
            
            elseif value == 6
                app.ButtonGroup.Visible = true;
                app.ButtonGroup_2.Visible = true;
                app.ButtonGroup_3.Visible = true;
                app.ButtonGroup_4.Visible = true;
                app.ButtonGroup_5.Visible = true;
                app.ButtonGroup_6.Visible = true;
                app.ButtonGroup_7.Visible = false;
                app.ButtonGroup_8.Visible = false;
            
            elseif value == 7
                app.ButtonGroup.Visible = true;
                app.ButtonGroup_2.Visible = true;
                app.ButtonGroup_3.Visible = true;
                app.ButtonGroup_4.Visible = true;
                app.ButtonGroup_5.Visible = true;
                app.ButtonGroup_6.Visible = true;
                app.ButtonGroup_7.Visible = true;
                app.ButtonGroup_8.Visible = false;
                
            elseif value == 8
                app.ButtonGroup.Visible = true;
                app.ButtonGroup_2.Visible = true;
                app.ButtonGroup_3.Visible = true;
                app.ButtonGroup_4.Visible = true;
                app.ButtonGroup_5.Visible = true;
                app.ButtonGroup_6.Visible = true;
                app.ButtonGroup_7.Visible = true;
                app.ButtonGroup_8.Visible = true;
            end
            
        end

        % Value changed function: BpmSpinner
        function gaugeChange(app, event)
            app.TempoKnob.Value = app.BpmSpinner.Value;
        end

        % Value changed function: Spinner
        function noteChange(app, event)
            if app.previousValue <  app.Spinner.Value
                while mod( app.Spinner.Value, 2) ~= 0 || mod( app.Spinner.Value, 3) == 0
                    app.Spinner.Value = app.Spinner.Value +1;
                end
            else
                while mod( app.Spinner.Value, 2) ~= 0 || app.Spinner.Value == 6
                    app.Spinner.Value = app.Spinner.Value -1;
                end
                if app.Spinner.Value == 0
                    app.Spinner.Value = 1;
                end
            end
        end

        % Value changing function: Spinner
        function setPreviousValue(app, event)
            app.previousValue = app.Spinner.Value;
            disp(app.previousValue)
        end

        % Menu selected function: stringsDropAMenu
        function aSevenString(app, event)
            app.value = 6;
            app.StandardEMenu.Checked = 'off';
            app.DropDMenu.Checked = 'off';
            app.StandardDMenu.Checked = 'off';
            app.DropCMenu.Checked = 'off';
            app.stringsBStandardMenu.Checked = 'off';
            app.stringsDropAMenu.Checked = 'on';
            app.Button.Visible = 'on';
            app.Button.Text = "E4 - " + num2str(app.Frequencies(1,6)) + " Hz";
            app.Button2.Visible = 'on';
            app.Button2.Text = "B3 - " + num2str(app.Frequencies(2,6))+ " Hz";
            app.Button3.Visible = 'on';
            app.Button3.Text = "G3 - " + num2str(app.Frequencies(3,6))+ " Hz";
            app.Button4.Visible = 'on';
            app.Button4.Text = "D3 - " + num2str(app.Frequencies(4,6))+ " Hz";
            app.Button5.Visible = 'on';
            app.Button5.Text = "A2 - " + num2str(app.Frequencies(5,6))+ " Hz";
            app.Button6.Visible = 'on';
            app.Button6.Text = "E2 - " + num2str(app.Frequencies(6,6))+ " Hz";  
            app.Button7.Visible = 'on';
            app.Button7.Text = "A1 - " + num2str(app.Frequencies(7,6))+ " Hz"; 
            chooseString(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GuimtarUIFigure = uifigure('Visible', 'off');
            app.GuimtarUIFigure.Name = 'Guimtar';
            app.ChooseyourtuningMenu = uimenu(app.GuimtarUIFigure);
            app.ChooseyourtuningMenu.Visible = 'off';
            app.ChooseyourtuningMenu.Text = 'Choose your tuning';
            app.StandardEMenu = uimenu(app.ChooseyourtuningMenu);
            app.StandardEMenu.MenuSelectedFcn = createCallbackFcn(app, @standardE, true);
            app.StandardEMenu.Text = 'Standard E';
            app.DropDMenu = uimenu(app.ChooseyourtuningMenu);
            app.DropDMenu.MenuSelectedFcn = createCallbackFcn(app, @dropD, true);
            app.DropDMenu.Text = 'Drop D';
            app.StandardDMenu = uimenu(app.ChooseyourtuningMenu);
            app.StandardDMenu.MenuSelectedFcn = createCallbackFcn(app, @standardD, true);
            app.StandardDMenu.Text = 'Standard D';
            app.DropCMenu = uimenu(app.ChooseyourtuningMenu);
            app.DropCMenu.MenuSelectedFcn = createCallbackFcn(app, @dropC, true);
            app.DropCMenu.Text = 'Drop C';
            app.stringsBStandardMenu = uimenu(app.ChooseyourtuningMenu);
            app.stringsBStandardMenu.MenuSelectedFcn = createCallbackFcn(app, @bSevenStandard, true);
            app.stringsBStandardMenu.Text = '7 strings B Standard';
            app.stringsDropAMenu = uimenu(app.ChooseyourtuningMenu);
            app.stringsDropAMenu.MenuSelectedFcn = createCallbackFcn(app, @aSevenString, true);
            app.stringsDropAMenu.Text = '7 strings Drop A';
            app.Image = uiimage(app.GuimtarUIFigure);
            app.Image.ImageSource = 'azure-linear-gradient-magenta-1920x1080-c2-04273d-33043d-a-60-f-14.jpg';
            app.Image2 = uiimage(app.GuimtarUIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @home, true);
            app.Image2.ImageSource = 'logo.png';
            app.Image3 = uiimage(app.GuimtarUIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @tunerOnClick, true);
            app.Image3.ImageSource = 'button_guitar-tuner.png';
            app.Image4 = uiimage(app.GuimtarUIFigure);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @metronomeOnClick, true);
            app.Image4.ImageSource = 'button_metronome.png';
            app.Image5 = uiimage(app.GuimtarUIFigure);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @soundRecorderOnClick, true);
            app.Image5.ImageSource = 'button_sound-recorder.png';
            app.Image6 = uiimage(app.GuimtarUIFigure);
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @tabsCollectionOnClick, true);
            app.Image6.ImageSource = 'button_tabs-collection.png';
            app.Image7 = uiimage(app.GuimtarUIFigure);
            app.Image7.ImageClickedFcn = createCallbackFcn(app, @myEquipmentOnClick, true);
            app.Image7.ImageSource = 'button_my-equipment.png';
            app.Button = uibutton(app.GuimtarUIFigure, 'push');
            app.Button.Visible = 'off';
            app.Button2 = uibutton(app.GuimtarUIFigure, 'push');
            app.Button2.Visible = 'off';
            app.Button2.Text = 'Button2';
            app.Button3 = uibutton(app.GuimtarUIFigure, 'push');
            app.Button3.Visible = 'off';
            app.Button3.Text = 'Button3';
            app.Button4 = uibutton(app.GuimtarUIFigure, 'push');
            app.Button4.Visible = 'off';
            app.Button4.Text = 'Button4';
            app.Button5 = uibutton(app.GuimtarUIFigure, 'push');
            app.Button5.Visible = 'off';
            app.Button5.Text = 'Button5';
            app.Button6 = uibutton(app.GuimtarUIFigure, 'push');
            app.Button6.Visible = 'off';
            app.Button6.Text = 'Button6';
            app.Button7 = uibutton(app.GuimtarUIFigure, 'push');
            app.Button7.Visible = 'off';
            app.Button7.Text = 'Button7';
            app.Image8 = uiimage(app.GuimtarUIFigure);
            app.Image8.ImageClickedFcn = createCallbackFcn(app, @home, true);
            app.Image8.ImageSource = 'home.png';
            app.Gauge = uigauge(app.GuimtarUIFigure, 'semicircular');
            app.Gauge.Visible = 'off';
            app.PitchLabel = uilabel(app.GuimtarUIFigure);
            app.PitchLabel.Visible = 'off';
            app.PitchLabel.Text = 'Pitch';
            app.DropDownLabel = uilabel(app.GuimtarUIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.GuimtarUIFigure);
            app.DropDown.Items = {'Standard E', 'Drop D', 'Standard D', 'Drop C', 'Standard B 7 Strings'};
            app.DropDown.Visible = 'off';
            app.DropDown.Value = 'Standard E';
            app.STARTButton = uibutton(app.GuimtarUIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Visible = 'off';
            app.STARTButton.Text = 'START';
            app.TempoKnobLabel = uilabel(app.GuimtarUIFigure);
            app.TempoKnobLabel.Visible = 'off';
            app.TempoKnobLabel.Text = 'Tempo';
            app.TempoKnob = uiknob(app.GuimtarUIFigure, 'continuous');
            app.TempoKnob.Limits = [60 300];
            app.TempoKnob.ValueChangedFcn = createCallbackFcn(app, @change, true);
            app.TempoKnob.Visible = 'off';
            app.TempoKnob.Value = 60;
            app.MetrumSpinnerLabel = uilabel(app.GuimtarUIFigure);
            app.MetrumSpinnerLabel.Visible = 'off';
            app.MetrumSpinnerLabel.Text = 'Metrum';
            app.MetrumSpinner = uispinner(app.GuimtarUIFigure);
            app.MetrumSpinner.Limits = [1 8];
            app.MetrumSpinner.ValueChangedFcn = createCallbackFcn(app, @MetrumSpinnerValueChanged, true);
            app.MetrumSpinner.Visible = 'off';
            app.MetrumSpinner.Value = 4;
            app.ButtonGroup = uibuttongroup(app.GuimtarUIFigure);
            app.ButtonGroup.Title = '1';
            app.ButtonGroup.Visible = 'off';
            app.tokButton = uitogglebutton(app.ButtonGroup);
            app.tokButton.Text = {'tok'; ''};
            app.takButton = uitogglebutton(app.ButtonGroup);
            app.takButton.Text = 'tak';
            app.tikButton = uitogglebutton(app.ButtonGroup);
            app.tikButton.Text = 'tik';
            app.tikButton.Value = true;
            app.ButtonGroup_2 = uibuttongroup(app.GuimtarUIFigure);
            app.ButtonGroup_2.Title = '2';
            app.ButtonGroup_2.Visible = 'off';
            app.tokButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.tokButton_2.Text = 'tok';
            app.takButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.takButton_2.Text = 'tak';
            app.tikButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.tikButton_2.Text = 'tik';
            app.tikButton_2.Value = true;
            app.ButtonGroup_3 = uibuttongroup(app.GuimtarUIFigure);
            app.ButtonGroup_3.Title = '3';
            app.ButtonGroup_3.Visible = 'off';
            app.tokButton_3 = uitogglebutton(app.ButtonGroup_3);
            app.tokButton_3.Text = 'tok';
            app.takButton_3 = uitogglebutton(app.ButtonGroup_3);
            app.takButton_3.Text = 'tak';
            app.tikButton_3 = uitogglebutton(app.ButtonGroup_3);
            app.tikButton_3.Text = {'tik'; ''};
            app.tikButton_3.Value = true;
            app.ButtonGroup_4 = uibuttongroup(app.GuimtarUIFigure);
            app.ButtonGroup_4.Title = '4';
            app.ButtonGroup_4.Visible = 'off';
            app.tokButton_4 = uitogglebutton(app.ButtonGroup_4);
            app.tokButton_4.Text = 'tok';
            app.takButton_4 = uitogglebutton(app.ButtonGroup_4);
            app.takButton_4.Text = 'tak';
            app.tikButton_4 = uitogglebutton(app.ButtonGroup_4);
            app.tikButton_4.Text = {'tik'; ''};
            app.tikButton_4.Value = true;
            app.ButtonGroup_5 = uibuttongroup(app.GuimtarUIFigure);
            app.ButtonGroup_5.Title = '5';
            app.ButtonGroup_5.Visible = 'off';
            app.tokButton_5 = uitogglebutton(app.ButtonGroup_5);
            app.tokButton_5.Text = 'tok';
            app.takButton_5 = uitogglebutton(app.ButtonGroup_5);
            app.takButton_5.Text = 'tak';
            app.tikButton_5 = uitogglebutton(app.ButtonGroup_5);
            app.tikButton_5.Text = 'tik';
            app.tikButton_5.Value = true;
            app.ButtonGroup_6 = uibuttongroup(app.GuimtarUIFigure);
            app.ButtonGroup_6.Title = '6';
            app.ButtonGroup_6.Visible = 'off';
            app.tokButton_6 = uitogglebutton(app.ButtonGroup_6);
            app.tokButton_6.Text = {'tok'; ''};
            app.takButton_6 = uitogglebutton(app.ButtonGroup_6);
            app.takButton_6.Text = {'tak'; ''};
            app.tikButton_6 = uitogglebutton(app.ButtonGroup_6);
            app.tikButton_6.Text = {'tik'; ''};
            app.tikButton_6.Value = true;
            app.ButtonGroup_7 = uibuttongroup(app.GuimtarUIFigure);
            app.ButtonGroup_7.Title = '7';
            app.ButtonGroup_7.Visible = 'off';
            app.tokButton_7 = uitogglebutton(app.ButtonGroup_7);
            app.tokButton_7.Text = {'tok'; ''};
            app.takButton_7 = uitogglebutton(app.ButtonGroup_7);
            app.takButton_7.Text = {'tak'; ''};
            app.tikButton_7 = uitogglebutton(app.ButtonGroup_7);
            app.tikButton_7.Text = 'tik';
            app.tikButton_7.Value = true;
            app.ButtonGroup_8 = uibuttongroup(app.GuimtarUIFigure);
            app.ButtonGroup_8.Title = '8';
            app.ButtonGroup_8.Visible = 'off';
            app.tokButton_8 = uitogglebutton(app.ButtonGroup_8);
            app.tokButton_8.Text = {'tok'; ''};
            app.takButton_8 = uitogglebutton(app.ButtonGroup_8);
            app.takButton_8.Text = 'tak';
            app.tikButton_8 = uitogglebutton(app.ButtonGroup_8);
            app.tikButton_8.Text = 'tik';
            app.tikButton_8.Value = true;
            app.BpmSpinnerLabel = uilabel(app.GuimtarUIFigure);
            app.BpmSpinnerLabel.Visible = 'off';
            app.BpmSpinnerLabel.Text = 'Bpm';
            app.BpmSpinner = uispinner(app.GuimtarUIFigure);
            app.BpmSpinner.Limits = [60 300];
            app.BpmSpinner.ValueChangedFcn = createCallbackFcn(app, @gaugeChange, true);
            app.BpmSpinner.Visible = 'off';
            app.BpmSpinner.Value = 60;
            app.Label = uilabel(app.GuimtarUIFigure);
            app.Label.Visible = 'off';
            app.Label.Text = '/    ';
            app.Spinner = uispinner(app.GuimtarUIFigure);
            app.Spinner.ValueChangingFcn = createCallbackFcn(app, @setPreviousValue, true);
            app.Spinner.Limits = [0 8];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @noteChange, true);
            app.Spinner.Visible = 'off';
            app.Spinner.Value = 4;
            % Show the figure after all components are created
            app.GuimtarUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GuimtarUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)STOPButtonPushed(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GuimtarUIFigure)
        end
    end
end